classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        UseAlphaMaskCheckBox         matlab.ui.control.CheckBox
        IsthereanobjecttoberemovedCheckBox  matlab.ui.control.CheckBox
        RemoveObjectButton_2         matlab.ui.control.Button
        OutlineObjectButton          matlab.ui.control.Button
        Label_6                      matlab.ui.control.Label
        CurrentlySelectedImageLabel  matlab.ui.control.Label
        TourintothePictureButton     matlab.ui.control.Button
        Label_5                      matlab.ui.control.Label
        VanishingPointLabel          matlab.ui.control.Label
        SelectVanishingPointButton   matlab.ui.control.Button
        Label_4                      matlab.ui.control.Label
        Point2Label                  matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        Point1Label                  matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        SelectPointsforInnerRectangleButton  matlab.ui.control.Button
        SelectImageButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        useAlphaMaskValue = false % Description
    end

    methods (Access = private)
        
        function  [img_left,img_right,img_ceil,img_floor,img_rect]= box(vp,p7,p2,focalLength,img)
            img=im2double(img);
            img_rect=img(p7(2):p2(2)-1,p7(1):p2(1)-1,:);        
            
            p1=[p7(1),p2(2)];
            p8=[p2(1),p7(2)];
            p11=getpointx(vp,p7,1);
            p9=getpointy(vp,p7,1);
            p3=getpointy(vp,p1,length(img(:,1,1)));
            p5=getpointx(vp,p1,1);
            p10=getpointy(vp,p8,1);
            p12=getpointx(vp,p8,length(img(1,:,1)));
            p6=getpointx(vp,p2,length(img(1,:,1)));
            p4=getpointy(vp,p2,length(img(:,1,1)));

            padding_left=0;
            padding_right=0;
            padding_ceil=0;
            padding_floor=0;
            
            if min(p11(2),p12(2))<0
                padding_ceil=abs(min(p11(2),p12(2)));
            end
            if min(p3(1),p9(1))<0
                padding_left=abs(min(p3(1),p9(1)));
            end
            if max(p5(2),p6(2))>length(img(:,1,1))
                padding_floor=abs(min(p5(2),p6(2))-length(img(:,1,1)));
            end    
            if max(p10(1),p4(1))>length(img(1,:,1))
                padding_right=abs( max(p10(1),p4(1))-length(img(1,:,1)));
            end
            
            img_new=zeros( padding_floor+length(img(:,1,1))+ padding_ceil,padding_left+length(img(1,:,1))+padding_right,3);
            
            img_new( padding_ceil+1:padding_ceil+length(img(:,1,1)),padding_left+1:padding_left+length(img(1,:,1)),:)=img;

            p1_new=[p1(1)+padding_left,p1(2)+padding_ceil];
            p2_new=[p2(1)+padding_left,p2(2)+padding_ceil];
            p3_new=[p3(1)+padding_left,p3(2)+padding_ceil];
            p4_new=[p4(1)+padding_left,p4(2)+padding_ceil];
            p5_new=[p5(1)+padding_left,p5(2)+padding_ceil];
            p6_new=[p6(1)+padding_left,p6(2)+padding_ceil];
            p7_new=[p7(1)+padding_left,p7(2)+padding_ceil];
            p8_new=[p8(1)+padding_left,p8(2)+padding_ceil];
            p9_new=[p9(1)+padding_left,p9(2)+padding_ceil];
            p10_new=[p10(1)+padding_left,p10(2)+padding_ceil];
            p11_new=[p11(1)+padding_left,p11(2)+padding_ceil];
            p12_new=[p12(1)+padding_left,p12(2)+padding_ceil];
            vp_new=[vp(1)+padding_left,vp(2)+padding_ceil];
 
            P_left=[p1_new;p5_new;p7_new;p11_new];
            P_right=[p2_new;p6_new;p8_new;p12_new];
            P_ceil=[p7_new;p8_new;p9_new;p10_new];
            P_floor=[p1_new;p2_new;p3_new;p4_new];
            depth_left=depth(abs(vp(1)-p1(1)),vp(1),focalLength);
            depth_right=depth(abs(vp(1)-p2(1)),length(img(1,:,1))-vp(1),focalLength);
            depth_ceil=depth(abs(vp(2)-p7(2)),vp(2),focalLength);
            depth_floor=depth(abs(vp(2)-p1(2)),length(img(:,1,1))-vp(2),focalLength);
            
            tform_left=fitgeotrans(P_left,[depth_left,p1(2)-p7(2);1,p1(2)-p7(2);depth_left,1;1,1;],'projective');
            tform_right=fitgeotrans(P_right,[1,p1(2)-p7(2);depth_right,p1(2)-p7(2);1,1;depth_right,1],'projective');
            tform_ceil=fitgeotrans(P_ceil,[1,depth_ceil;p8(1)-p7(1),depth_ceil;1,1;p8(1)-p7(1),1],'projective');
            tform_floor=fitgeotrans(P_floor,[1,1;p8(1)-p7(1),1;1,depth_floor;p8(1)-p7(1),depth_floor],'projective');

             img_left=imwarp(img_new,tform_left,'OutputView', imref2d([p1(2)-p7(2),depth_left]));
             img_right=imwarp(img_new,tform_right,'OutputView', imref2d([p1(2)-p7(2),depth_right]));
             img_ceil=imwarp(img_new,tform_ceil,'OutputView', imref2d([depth_ceil,p8(1)-p7(1)]));
             img_floor=imwarp(img_new,tform_floor,'OutputView', imref2d([depth_floor,p8(1)-p7(1)]));
        end
  
        function H = getH(P1, P2)
            x = P1(:, 1);
            y = P1(:, 2);
            X = P2(:, 1);
            Y = P2(:, 2);
            A = zeros(length(x(:))*2,9);
            for i = 1:length(x(:))
                a = [x(i),y(i),1];
                b = [0 0 0];
                c = [X(i);Y(i)];
                d = -c*a;
                A((i-1)*2+1:(i-1)*2+2,1:9) = [[a b;b a] d];
            end
            [U,S,V] = svd(A);
            h = V(:,9);
            H = reshape(h,[3,3])';
        end
        
        function drawline(FDB)
            s=size(FDB);
            
            n=1:1:s(2);
            
            for i=n
            
                line([FDB{1,i} FDB{1,i}+FDB{3,i}],[FDB{2,i} FDB{2,i}],'Color','r');
                
                line([FDB{1,i}+FDB{3,i} FDB{1,i}+FDB{3,i}],[FDB{2,i} FDB{2,i}+FDB{4,i}],'Color','r');
                
                line([FDB{1,i}+FDB{3,i} FDB{1,i}],[FDB{2,i}+FDB{4,i} FDB{2,i}+FDB{4,i}],'Color','r');
                
                line([FDB{1,i} FDB{1,i}],[FDB{2,i}+FDB{4,i} FDB{2,i}],'Color','r');
            end
        end
        
        function [k,b] = getline(point1,point2)
                k=(point1(2)-point2(2))/(point1(1)-point2(1));
                b=point1(2)-k*point1(1);
        end
        
         function d = depth(a,h,focalLength)
            d=floor(h*focalLength/a-focalLength);           
         end
         
        function P = getpointx(point1,point2,x)
            k=(point1(2)-point2(2))/(point1(1)-point2(1));
            b=point1(2)-k*point1(1);
            y=floor(k*x+b);
            P=[x,y];
        end
        
        function P = getpointy(point1,point2,y)
            k=(point1(2)-point2(2))/(point1(1)-point2(1));
            b=point1(2)-k*point1(1);
            x=floor((y-b)/k);
            P=[x,y];
        end
        
        function P2 = WarpH(P1, H)
            x = P1(:, 1);
            y = P1(:, 2);
            p1 = [x'; y'; ones(1, length(x))];
            q1 = H*p1;
            q1 = q1./[q1(3, :); q1(3,:); q1(3, :)];
            q1(3,:)=[];
            P2 = q1';
        end
        
        function clickCallback(~,evt)
            if strcmp(evt.SelectionType,'double')
                uiresume;
            end
        end
        
        function draw_vp(app, vp, p1, p2, p7, p8, rows, cols)
            global vp_plot1 vp_plot2 vp_plot3 vp_plot4 vp_plot5
            delete(vp_plot1)
            delete(vp_plot2)
            delete(vp_plot3)
            delete(vp_plot4)
            delete(vp_plot5)
            if (app.Label_2 ~= '-') && (app.Label_4 ~= '-')
                V1 = p1 - vp;
                V2 = p2 - vp;
                V3 = p7 - vp;
                V4 = p8 - vp;
                factor = max(floor(rows/(p2(1)-p7(1))), floor(cols/(p7(2)-p2(2))))
                pext1 = p1 + factor*V1;
                pext2 = p2 + factor*V2;
                pext3 = p7 + factor*V3;
                pext4 = p8 + factor*V4;
                %vp_plot1 = plot(vp(1),vp(2), 'r+', 'MarkerSize', 10, 'LineWidth', 2)
                vp_plot2 = plot([vp(1),pext1(1)],[vp(2),pext1(2)], 'r', 'LineWidth', 2)
                vp_plot3 = plot([vp(1),pext2(1)],[vp(2),pext2(2)],'r', 'LineWidth', 2)
                vp_plot4 = plot([vp(1),pext3(1)],[vp(2),pext3(2)],'r', 'LineWidth', 2)
                vp_plot5 = plot([vp(1),pext4(1)],[vp(2),pext4(2)],'r', 'LineWidth', 2)
            end
        end

        function draw_rect(app, p2, p7)
            global rect_plot
            delete(rect_plot)
            rect_plot = rectangle('Position',[p7(1) p7(2) (p2(1)-p7(1)) (p2(2)-p7(2))],'EdgeColor','r', 'LineWidth', 2)
                       
        end
       
        
       function mycallback(app,src,event)
            if event.SelectedOption == "OK"
                delete(app.UIFigure);
            end
        end


    end
      

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(app, event)
            global str
            global pathname
            
            [filename,pathname]=uigetfile({'*.bmp;*.jpg;*.png;*.jpeg;*.tif'})
            if filename == '-'
                filename = 'temp.png';
            end
            str=[pathname filename];
            global im I
            im = imread(str);
            app.Image.ImageSource = im;
            I = im;
            app.Label_6.Text = filename;
            figure(app.UIFigure);
            msgbox("Press the button to select two diagonal points in order to create a background rectangle.","Info","info");
        end

        % Button pushed function: SelectPointsforInnerRectangleButton
        function SelectPointsforInnerRectangleButtonPushed(app, event)
            global im vp p1 p2 p7 p8 rows cols I rect_plot  p7_roi p2_roi 
           
            if (app.Label_6.Text == "temp.png")
                image = imshow(I);
            else
                image = imshow(im);
            end

            [x,y]=ginput(2);
            p7=[min(x(1),x(2)),min(y(1),y(2))];
            p2=[max(x(1),x(2)),max(y(1),y(2))];
            draw_rect(app, p2, p7)
            p7_roi = drawpoint('Position',[x(1) y(1)]);
            p2_roi = drawpoint('Position',[x(2) y(2)]);
            addlistener(p7_roi,'ROIMoved',@roiCallback_p7);
            addlistener(p2_roi,'ROIMoved',@roiCallback_p2);
         
            
            app.Label_2.Text = "X: " + string(round(p7(1), 4)) + "    Y: " + string(round(p7(2), 4));
            app.Label_4.Text = "X: " + string(round(p2(1), 4)) + "    Y: " + string(round(p2(2), 4));
            %rect_plot = rectangle('Position',[p7(1) p7(2) (p2(1)-p7(1)) (p2(2)-p7(2))],'EdgeColor','r', 'LineWidth', 2)
            
            axis on;
            hold on;
            p1 = [p7(1), p2(2)];
            p8 = [p2(1), p7(2)];
            [rows, cols, ~] = size(image);
            msgbox("Press the button to select the vanishing point in the image.","Info","info");
            function roiCallback_p7(src,evt)
                evname = evt.EventName;
                switch evname
                    case{'ROIMoved'}
                        p7 = evt.CurrentPosition;
                        p1 = [p7(1), p2(2)];
                        p8 = [p2(1), p7(2)];
                        app.Label_2.Text = "X: " + string(round(p7(1), 4)) + "    Y: " + string(round(p7(2), 4));
                        draw_rect(app, p2, p7)
                        draw_vp(app, vp, p1, p2, p7, p8, rows, cols)
                end
            end
            function roiCallback_p2(src,evt)
                evname = evt.EventName;
                switch evname
                    case{'ROIMoved'}
                        p2 = evt.CurrentPosition;
                        p1 = [p7(1), p2(2)];
                        p8 = [p2(1), p7(2)];
                        app.Label_4.Text = "X: " + string(round(p2(1), 4)) + "    Y: " + string(round(p2(2), 4));
                        draw_rect(app, p2, p7)
                        draw_vp(app, vp, p1, p2, p7, p8, rows, cols)
                end
        
            end
            
        end

        % Button pushed function: SelectVanishingPointButton
        function SelectVanishingPointButtonPushed(app, event)
            
            global vp p1 p2 p7 p8 rows cols vp_roi
            [x,y]=ginput(1);
            vp = [x,y]; 
            app.Label_5.Text = "X: " + string(round(vp(1), 4)) + "    Y: " + string(round(vp(2), 4));
            draw_vp(app, vp, p1, p2, p7, p8, rows, cols)
            vp_roi = drawpoint('Position',[x y]);
            addlistener(vp_roi,'ROIMoved',@roiCallback);

            msgbox(["Is there any object in the foreground?";"If yes tick the checkbox.";"If no you can run the program. :) ";"Please notice that only Objects connected to the floor can be removed."], "Info","info");

            function roiCallback(src,evt)
                evname = evt.EventName;
                switch evname
                    case{'ROIMoved'}
                        vp = evt.CurrentPosition;
                        app.Label_5.Text = "X: " + string(round(vp(1), 4)) + "    Y: " + string(round(vp(2), 4));
                        draw_vp(app, vp, p1, p2, p7, p8, rows, cols)
                end
        
            end
            
        end

        % Button pushed function: TourintothePictureButton
        function TourintothePictureButtonPushed(app, event)
            global p2 p7 vp I mask
            vp=floor(vp);
            p2=floor(p2);
            p7=floor(p7);
            img = I;
            close all;
            
            % TIP FUNCTIONS
            %fig = tip(img, vp, p7, p2, 'useAlpha', false);
            fig = tip(img, vp, p7, p2, 'useAlpha', app.useAlphaMaskValue, 'fgMask', mask, 'f', 400); % David
            set(gcf, 'Name', 'TIP');
            tb = cameratoolbar(fig);
            
            
            %fig2 = box3d(img, vp, p7, p2); % Yan, Shi
            %set(gcf, 'Name', 'Box3D');

        end

        % Button pushed function: OutlineObjectButton
        function OutlineObjectButtonPushed(app, event)
            global str I overWrittenImage
            I = imread(str);
            if (app.Label_6.Text == "temp.png")
                I = overWrittenImage;
            else
                imshow(I,[]);
            end
            
            global outline
            outline = drawassisted();   
        end

        % Value changed function: IsthereanobjecttoberemovedCheckBox
        function IsthereanobjecttoberemovedCheckBoxValueChanged(app, event)
            value = app.IsthereanobjecttoberemovedCheckBox.Value;
            app.OutlineObjectButton.Enable = value;
            app.RemoveObjectButton_2.Enable = value;
            msgbox("You can now outline the object and remove it from the image.","Info","info");
        end

        % Button pushed function: RemoveObjectButton_2
        function RemoveObjectButton_2Pushed(app, event)
            global outline
            global I
            global pathname
            global mask
            mask = createMask(outline);
            j = inpaintExemplar(I,mask);
            fullDestinationFileName = fullfile(pathname, "temp.png")
            imwrite(j, fullDestinationFileName)
            close all
            global overWrittenImage
            overWrittenImage = imread(fullDestinationFileName);
            app.Image.ImageSource = overWrittenImage;
            I = overWrittenImage;
            %app.Image.ImageSource = "temp.png";
            app.Label_6.Text = "temp.png";
            imshow(overWrittenImage);
            msgbox("Press 'Tour into the Picture!' Button in order to run the program and get the 3D Reconstruction.","Info","info");
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
             uiconfirm(app.UIFigure,'Close program?','Confirm Close', ...
                'CloseFcn',@app.mycallback);
        end

        % Value changed function: UseAlphaMaskCheckBox
        function UseAlphaMaskCheckBoxValueChanged(app, event)
            app.useAlphaMaskValue = app.UseAlphaMaskCheckBox.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SelectImageButton = uibutton(app.GridLayout, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Text = 'Select Image';
            app.SelectPointsforInnerRectangleButton = uibutton(app.GridLayout, 'push');
            app.SelectPointsforInnerRectangleButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPointsforInnerRectangleButtonPushed, true);
            app.SelectPointsforInnerRectangleButton.Text = 'Select Points for Inner Rectangle';
            app.Image = uiimage(app.GridLayout);
            app.Point1Label = uilabel(app.GridLayout);
            app.Point1Label.Text = 'Point 1:';
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = '-';
            app.Point2Label = uilabel(app.GridLayout);
            app.Point2Label.Text = 'Point 2:';
            app.Label_4 = uilabel(app.GridLayout);
            app.Label_4.Text = '-';
            app.SelectVanishingPointButton = uibutton(app.GridLayout, 'push');
            app.SelectVanishingPointButton.ButtonPushedFcn = createCallbackFcn(app, @SelectVanishingPointButtonPushed, true);
            app.SelectVanishingPointButton.Text = 'Select Vanishing Point';
            app.VanishingPointLabel = uilabel(app.GridLayout);
            app.VanishingPointLabel.Text = 'Vanishing Point:';
            app.Label_5 = uilabel(app.GridLayout);
            app.Label_5.Text = '-';
            app.TourintothePictureButton = uibutton(app.GridLayout, 'push');
            app.TourintothePictureButton.ButtonPushedFcn = createCallbackFcn(app, @TourintothePictureButtonPushed, true);
            app.TourintothePictureButton.Text = 'Tour into the Picture!';
            app.CurrentlySelectedImageLabel = uilabel(app.GridLayout);
            app.CurrentlySelectedImageLabel.Text = 'Currently Selected Image:';
            app.Label_6 = uilabel(app.GridLayout);
            app.Label_6.Text = '-';
            app.OutlineObjectButton = uibutton(app.GridLayout, 'push');
            app.OutlineObjectButton.ButtonPushedFcn = createCallbackFcn(app, @OutlineObjectButtonPushed, true);
            app.OutlineObjectButton.Enable = 'off';
            app.OutlineObjectButton.Text = 'Outline Object';
            app.RemoveObjectButton_2 = uibutton(app.GridLayout, 'push');
            app.RemoveObjectButton_2.ButtonPushedFcn = createCallbackFcn(app, @RemoveObjectButton_2Pushed, true);
            app.RemoveObjectButton_2.Enable = 'off';
            app.RemoveObjectButton_2.Text = 'Remove Object';
            app.IsthereanobjecttoberemovedCheckBox = uicheckbox(app.GridLayout);
            app.IsthereanobjecttoberemovedCheckBox.ValueChangedFcn = createCallbackFcn(app, @IsthereanobjecttoberemovedCheckBoxValueChanged, true);
            app.IsthereanobjecttoberemovedCheckBox.Text = 'Is there an object to be removed?';
            app.UseAlphaMaskCheckBox = uicheckbox(app.GridLayout);
            app.UseAlphaMaskCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseAlphaMaskCheckBoxValueChanged, true);
            app.UseAlphaMaskCheckBox.Text = 'Use Alpha Mask?';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end