classdef TIP_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        UseAlphaMaskLabel            matlab.ui.control.Label
        DepthSlider                  matlab.ui.control.Slider
        DepthSliderLabel             matlab.ui.control.Label
        CheckBox                     matlab.ui.control.CheckBox
        AddForegroundObjectButton    matlab.ui.control.Button
        Label_6                      matlab.ui.control.Label
        CurrentlySelectedImageLabel  matlab.ui.control.Label
        TourintothePictureButton     matlab.ui.control.Button
        Label_5                      matlab.ui.control.Label
        VanishingPointLabel          matlab.ui.control.Label
        SelectVanishingPointButton   matlab.ui.control.Button
        Label_4                      matlab.ui.control.Label
        Point2Label                  matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        Point1Label                  matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        SelectPointsforInnerRectangleButton  matlab.ui.control.Button
        SelectImageButton            matlab.ui.control.Button
    end


    methods (Access = private)
        
        function drawline(FDB)
            s=size(FDB);
            
            n=1:1:s(2);
            
            for i=n
            
                line([FDB{1,i} FDB{1,i}+FDB{3,i}],[FDB{2,i} FDB{2,i}],'Color','r');
                
                line([FDB{1,i}+FDB{3,i} FDB{1,i}+FDB{3,i}],[FDB{2,i} FDB{2,i}+FDB{4,i}],'Color','r');
                
                line([FDB{1,i}+FDB{3,i} FDB{1,i}],[FDB{2,i}+FDB{4,i} FDB{2,i}+FDB{4,i}],'Color','r');
                
                line([FDB{1,i} FDB{1,i}],[FDB{2,i}+FDB{4,i} FDB{2,i}],'Color','r');
            end
        end
        
        function [k,b] = getline(point1,point2)
                k=(point1(2)-point2(2))/(point1(1)-point2(1));
                b=point1(2)-k*point1(1);
        end
        
        function clickCallback(~,evt)
            if strcmp(evt.SelectionType,'double')
                uiresume;
            end
        end
        
        function draw_vp(app, vp, p1, p2, p7, p8, rows, cols)
            global vp_plot1 vp_plot2 vp_plot3 vp_plot4 vp_plot5
            delete(vp_plot1)
            delete(vp_plot2)
            delete(vp_plot3)
            delete(vp_plot4)
            delete(vp_plot5)
            if (app.Label_2 ~= '-') && (app.Label_4 ~= '-')
                V1 = p1 - vp;
                V2 = p2 - vp;
                V3 = p7 - vp;
                V4 = p8 - vp;
                factor = max(floor(rows/(p2(1)-p7(1))), floor(cols/(p7(2)-p2(2))))
                pext1 = p1 + factor*V1;
                pext2 = p2 + factor*V2;
                pext3 = p7 + factor*V3;
                pext4 = p8 + factor*V4;
                %vp_plot1 = plot(vp(1),vp(2), 'r+', 'MarkerSize', 10, 'LineWidth', 2)
                vp_plot2 = plot([vp(1),pext1(1)],[vp(2),pext1(2)], 'r', 'LineWidth', 2)
                vp_plot3 = plot([vp(1),pext2(1)],[vp(2),pext2(2)],'r', 'LineWidth', 2)
                vp_plot4 = plot([vp(1),pext3(1)],[vp(2),pext3(2)],'r', 'LineWidth', 2)
                vp_plot5 = plot([vp(1),pext4(1)],[vp(2),pext4(2)],'r', 'LineWidth', 2)
            end
        end

        function draw_rect(app, p2, p7)
            global rect_plot
            delete(rect_plot)
            rect_plot = rectangle('Position',[p7(1) p7(2) (p2(1)-p7(1)) (p2(2)-p7(2))],'EdgeColor','r', 'LineWidth', 2)
                       
        end
       
        
       function mycallback(app,src,event)
            if event.SelectedOption == "OK"
                delete(app.UIFigure);
                clear global
            end
        end


    end
      

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Remove(app)
            
        end

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(app, event)
            clear global
            close all
            global str
            global pathname
            
            [filename,pathname]=uigetfile({'*.bmp;*.jpg;*.png;*.jpeg;*.tif'});
            if filename == '-'
                filename = 'temp.png';
            end
            str=[pathname filename];
            global im I
            im = imread(str);
            
            if size(im,1)*size(im,2) > 2000*2000
                ratio = sqrt((2000*2000)/(size(im,1)*size(im,2)));
                im = imresize(im,ratio);
                size(im)
            end
            app.Image.ImageSource = im;
            I = im;
            app.Label_6.Text = filename;
            figure(app.UIFigure);
            app.SelectPointsforInnerRectangleButton.Enable = true;
            
            msgbox("Press the button to select two diagonal points in order to create a background rectangle.","Info","info");
        end

        % Button pushed function: SelectPointsforInnerRectangleButton
        function SelectPointsforInnerRectangleButtonPushed(app, event)
            close all
            global im vp p1 p2 p7 p8 rows cols I rect_plot  p7_roi p2_roi 
           
            if (app.Label_6.Text == "temp.png")
                image = imshow(I);
            else
                image = imshow(im);
            end

            [x,y]=ginput(2);
            p7=[min(x(1),x(2)),min(y(1),y(2))];
            p2=[max(x(1),x(2)),max(y(1),y(2))];
            draw_rect(app, p2, p7)
            p7_roi = drawpoint('Position',[x(1) y(1)]);
            p2_roi = drawpoint('Position',[x(2) y(2)]);
            addlistener(p7_roi,'ROIMoved',@roiCallback_p7);
            addlistener(p2_roi,'ROIMoved',@roiCallback_p2);
         
            
            app.Label_2.Text = "X: " + string(round(p7(1), 4)) + "    Y: " + string(round(p7(2), 4));
            app.Label_4.Text = "X: " + string(round(p2(1), 4)) + "    Y: " + string(round(p2(2), 4));
            %rect_plot = rectangle('Position',[p7(1) p7(2) (p2(1)-p7(1)) (p2(2)-p7(2))],'EdgeColor','r', 'LineWidth', 2)
            app.SelectVanishingPointButton.Enable = true;
            axis on;
            hold on;
            p1 = [p7(1), p2(2)];
            p8 = [p2(1), p7(2)];
            [rows, cols, ~] = size(image);
            msgbox("Press the button to select the vanishing point in the image.","Info","info");
            function roiCallback_p7(src,evt)
                evname = evt.EventName;
                switch evname
                    case{'ROIMoved'}
                        p7 = evt.CurrentPosition;
                        p1 = [p7(1), p2(2)];
                        p8 = [p2(1), p7(2)];
                        app.Label_2.Text = "X: " + string(round(p7(1), 4)) + "    Y: " + string(round(p7(2), 4));
                        draw_rect(app, p2, p7);
                        draw_vp(app, vp, p1, p2, p7, p8, rows, cols);
                end
            end
            function roiCallback_p2(src,evt)
                evname = evt.EventName;
                switch evname
                    case{'ROIMoved'}
                        p2 = evt.CurrentPosition;
                        p1 = [p7(1), p2(2)];
                        p8 = [p2(1), p7(2)];
                        app.Label_4.Text = "X: " + string(round(p2(1), 4)) + "    Y: " + string(round(p2(2), 4));
                        draw_rect(app, p2, p7);
                        draw_vp(app, vp, p1, p2, p7, p8, rows, cols);
                end
        
            end
            
        end

        % Button pushed function: SelectVanishingPointButton
        function SelectVanishingPointButtonPushed(app, event)
            
            global vp p1 p2 p7 p8 rows cols vp_roi mask
            [x,y]=ginput(1);
            vp = [x,y]; 
            app.Label_5.Text = "X: " + string(round(vp(1), 4)) + "    Y: " + string(round(vp(2), 4));
            draw_vp(app, vp, p1, p2, p7, p8, rows, cols)
            vp_roi = drawpoint('Position',[x y]);
            addlistener(vp_roi,'ROIMoved',@roiCallback);
            
            app.AddForegroundObjectButton.Enable = true;
            mask = false;
            app.TourintothePictureButton.Enable = true;
            
            msgbox(["Is there any object in the foreground?";"If yes tick the checkbox.";"If no you can run the program. :) "], "Info","info");

            function roiCallback(src,evt)
                evname = evt.EventName;
                switch evname
                    case{'ROIMoved'}
                        vp = evt.CurrentPosition;
                        app.Label_5.Text = "X: " + string(round(vp(1), 4)) + "    Y: " + string(round(vp(2), 4));
                        draw_vp(app, vp, p1, p2, p7, p8, rows, cols)
                end
        
            end
            
        end

        % Button pushed function: TourintothePictureButton
        function TourintothePictureButtonPushed(app, event)
            global p2 p7 vp I mask focal_length use_alpha
            vp=floor(vp);
            p2=floor(p2);
            p7=floor(p7);
            focal_length = app.DepthSlider.Value;
            use_alpha = app.CheckBox.Value;
            img = I;
            close all;
            
            % TIP FUNCTIONS
            fig = tip(img, vp, p7, p2, 'useAlpha', use_alpha, 'fgMask', mask, 'f', focal_length);
            set(gcf, 'Name', 'TIP');
            cameratoolbar(fig);

        end

        % Button pushed function: AddForegroundObjectButton
        function AddForegroundObjectButtonPushed(app, event)
            global str I overWrittenImage
            I = imread(str);
            if (app.Label_6.Text == "temp.png")
                I = overWrittenImage;
            else
                imshow(I,[]);
            end
            
            global outline mask
            outline = drawassisted();
            mask = createMask(outline);
            
            global im im_masked
            im_masked = imoverlay(im, mask, 'yellow');
            app.Image.ImageSource = im_masked;
            
        end

        % Callback function
        function IsthereanobjecttoberemovedCheckBoxValueChanged(app, event)
            value = app.IsthereanobjecttoberemovedCheckBox.Value;
            app.AddForegroundObjectButton.Enable = value;
            %app.RemoveObjectButton_2.Enable = value;
            msgbox("You can now outline the object and remove it from the image.","Info","info");
        end

        % Callback function
        function RemoveObjectButton_2Pushed(app, event)
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
             uiconfirm(app.UIFigure,'Close program?','Confirm Close', ...
                'CloseFcn',@app.mycallback);
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
        end

        % Value changed function: DepthSlider
        function DepthSliderValueChanged(app, event)
            global focal_length
            focal_length = app.DepthSlider.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SelectImageButton = uibutton(app.GridLayout, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Text = 'Select Image';
            app.SelectPointsforInnerRectangleButton = uibutton(app.GridLayout, 'push');
            app.SelectPointsforInnerRectangleButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPointsforInnerRectangleButtonPushed, true);
            app.SelectPointsforInnerRectangleButton.Enable = 'off';
            app.SelectPointsforInnerRectangleButton.Text = 'Select Points for Inner Rectangle';
            app.Image = uiimage(app.GridLayout);
            app.Point1Label = uilabel(app.GridLayout);
            app.Point1Label.Text = 'Point 1:';
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = '-';
            app.Point2Label = uilabel(app.GridLayout);
            app.Point2Label.Text = 'Point 2:';
            app.Label_4 = uilabel(app.GridLayout);
            app.Label_4.Text = '-';
            app.SelectVanishingPointButton = uibutton(app.GridLayout, 'push');
            app.SelectVanishingPointButton.ButtonPushedFcn = createCallbackFcn(app, @SelectVanishingPointButtonPushed, true);
            app.SelectVanishingPointButton.Enable = 'off';
            app.SelectVanishingPointButton.Text = 'Select Vanishing Point';
            app.VanishingPointLabel = uilabel(app.GridLayout);
            app.VanishingPointLabel.Text = 'Vanishing Point:';
            app.Label_5 = uilabel(app.GridLayout);
            app.Label_5.Text = '-';
            app.TourintothePictureButton = uibutton(app.GridLayout, 'push');
            app.TourintothePictureButton.ButtonPushedFcn = createCallbackFcn(app, @TourintothePictureButtonPushed, true);
            app.TourintothePictureButton.Enable = 'off';
            app.TourintothePictureButton.Text = 'Tour into the Picture!';
            app.CurrentlySelectedImageLabel = uilabel(app.GridLayout);
            app.CurrentlySelectedImageLabel.Text = 'Currently Selected Image:';
            app.Label_6 = uilabel(app.GridLayout);
            app.Label_6.Text = '-';
            app.AddForegroundObjectButton = uibutton(app.GridLayout, 'push');
            app.AddForegroundObjectButton.ButtonPushedFcn = createCallbackFcn(app, @AddForegroundObjectButtonPushed, true);
            app.AddForegroundObjectButton.Enable = 'off';
            app.AddForegroundObjectButton.Text = 'Add Foreground Object';
            app.CheckBox = uicheckbox(app.GridLayout);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.DepthSliderLabel = uilabel(app.GridLayout);
            app.DepthSliderLabel.Text = 'Depth';
            app.DepthSlider = uislider(app.GridLayout);
            app.DepthSlider.Limits = [50 1000];
            app.DepthSlider.MajorTicks = [50 1000];
            app.DepthSlider.MajorTickLabels = {'Min', 'Max'};
            app.DepthSlider.ValueChangedFcn = createCallbackFcn(app, @DepthSliderValueChanged, true);
            app.DepthSlider.Value = 250;
            app.UseAlphaMaskLabel = uilabel(app.GridLayout);
            app.UseAlphaMaskLabel.Text = 'Use Alpha Mask';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TIP_APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @Remove)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end